classdef DiceGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        StatusLabel     matlab.ui.control.Label
        SkipButton      matlab.ui.control.Button
        UITable         matlab.ui.control.Table
        dice2           matlab.ui.control.Image
        dice3           matlab.ui.control.Image
        dice4           matlab.ui.control.Image
        dice6           matlab.ui.control.Image
        dice5           matlab.ui.control.Image
        dice1           matlab.ui.control.Image
        RollDiceButton  matlab.ui.control.Button
        PlayButton      matlab.ui.control.Button
        secondbg        matlab.ui.control.Image
        menubg          matlab.ui.control.Image
    end

    
    properties (Access = private)
        Sensor
        GestureDetector
        Game% Description


        %% ThinkSpeak Online
        ChannelID = "3383747"
        WriteKey = "7OOJBDU1FXB2AAU5"
        ReadKey = "3FLYFWUQZSVKN5W3"
        PlayerNum = 1
        SyncTimer
        GestureTimer


        % NEW: Audio player property to hold the music object
        AudioPlayer
        % NEW: Store audio data so we can loop it
        AudioData
        AudioSampleRate

         % NEW: Dice roll sound player (plays only when Roll Dice is pressed)
        DiceAudioPlayer
        DiceAudioData
        DiceAudioSampleRate
        
    end
    
    methods (Access = private)


        function RestartMusic(app, ~, ~)
            if ~isempty(app.AudioPlayer) && isvalid(app.AudioPlayer)
                play(app.AudioPlayer);
            end
        end


       function UpdateGameDisplay(app)
            if isempty(app.Game)
                return;
            end

            app.UITable.Data = {app.Game.P1Score, app.Game.P2Score, app.Game.TargetNumber};

        if ~app.Game.GameOver
            statusText = app.Game.getTurnStatus();
            rollsLeft = app.Game.getRollsRemaining();
            if rollsLeft > 0
                statusText = sprintf('%s (%d roll(s) left this turn)', statusText, rollsLeft);
            end
            app.StatusLabel.Text = statusText;
        else
            app.StatusLabel.Text = sprintf('GAME OVER! Player %d wins!', app.Game.Winner);
        end
    
        isMyTurn = ~app.Game.GameOver && app.Game.CurrentPlayer == app.PlayerNum;
        if isMyTurn
            app.RollDiceButton.Enable = "on";
            app.SkipButton.Enable = "on";
        else
            app.RollDiceButton.Enable = "off";
            app.SkipButton.Enable = "off";
        end
       end
       function action = checkGesture(app)
            MyTurn = (app.PlayerNum == 1 && app.Game.CurrentPlayer == 1) || (app.PlayerNum == 2 && app.Game.CurrentPlayer == 2);
            %% for Gio add something to check if its the players turn
            if ~MyTurn 
                action = [];
                return
            end

             try
                app.Sensor.startLogging();   % must exist in sensorAnalyzer
                action = app.GestureDetector.detect();
            catch
                action = [];
             
            end


        end
        function pollGestures(app)
        % Called by timer – check for gesture and trigger action
            action = app.checkGesture();
            if isempty(action)
                return;
            end
            switch action
                case "Shake"
                    app.RollDiceButtonPushed();
                case "Skip"
                    app.SkipButtonPushed();
            end
        end
        function startGesturePolling(app)
    % Stop any existing timer
            if ~isempty(app.GestureTimer) && isvalid(app.GestureTimer)
                stop(app.GestureTimer);
                delete(app.GestureTimer);
            end
            % Create a new timer that fires every 0.9 seconds
            app.GestureTimer = timer('ExecutionMode', 'fixedRate', ...
                'Period', 0.9, ...
                'TimerFcn', @(~,~) app.pollGestures());
            start(app.GestureTimer);
        end
        
        function stopGesturePolling(app)
            if ~isempty(app.GestureTimer) && isvalid(app.GestureTimer)
                stop(app.GestureTimer);
                delete(app.GestureTimer);
            end
        end


    function SelectPlayerNumber(app)
    answer = inputdlg({'Enter player number for this device (1 or 2):'}, ...
        'ThingSpeak Player Setup', [1 45], {num2str(app.PlayerNum)});
    if isempty(answer)
        return;
    end

    playerNumber = str2double(answer{1});
    if playerNumber == 1 || playerNumber == 2
        app.PlayerNum = playerNumber;
    else
        uialert(app.UIFigure, 'Player number must be 1 or 2.', 'Invalid Player');
        app.SelectPlayerNumber();
    end
    end

function ShowDiceFace(app, roll)
    diceComponents = {app.dice1, app.dice2, app.dice3, ...
                      app.dice4, app.dice5, app.dice6};
    for k = 1:6
        diceComponents{k}.Visible = "off";
    end
    if roll >= 1 && roll <= 6
        diceComponents{roll}.Visible = "on";
    end
end

function StartSyncTimer(app)
    app.StopSyncTimer();
    app.SyncTimer = timer('ExecutionMode', 'fixedSpacing', ...
        'Period', 5, ...
        'BusyMode', 'drop', ...
        'TimerFcn', @(~, ~) app.SyncFromThingSpeak());
    start(app.SyncTimer);
end

function StopSyncTimer(app)
    if ~isempty(app.SyncTimer) && isvalid(app.SyncTimer)
        stop(app.SyncTimer);
        delete(app.SyncTimer);
    end
    app.SyncTimer = [];
end

function SyncFromThingSpeak(app)
    if isempty(app.Game) || isempty(app.Game.Network)
        return;
    end

    didUpdate = app.Game.readThingSpeakState();
    if didUpdate
        app.ShowDiceFace(app.Game.LastRoll);
        app.UpdateGameDisplay();
        drawnow limitrate;
    end
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
             
         % NEW: Restart music when it finishes (creates the loop effect)
       
            pathToMLAPP = fileparts(mfilename('fullpath'));
            musicFile = fullfile(pathToMLAPP, 'green.mp3');

            [app.AudioData, app.AudioSampleRate] = audioread(musicFile);
             app.AudioPlayer = audioplayer(app.AudioData, app.AudioSampleRate);

                % When the song ends, call RestartMusic to loop it
                app.AudioPlayer.StopFcn = createCallbackFcn(app, @RestartMusic, true);

                play(app.AudioPlayer);

                warning('DiceGame:AudioError', 'Could not load menu music: %s');

               
                app.StopSyncTimer();
     

        end

        % Callback function
        function Start(app, event)
           
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

          app.SelectPlayerNumber();
app.Game = GameState();
app.Game.enableThingSpeak(str2double(app.ChannelID), app.WriteKey, app.ReadKey, app.PlayerNum == 1);

if app.PlayerNum == 2
    app.Game.readThingSpeakState();
end

app.UpdateGameDisplay();
app.StartSyncTimer();

            app.menubg.Visible = "off";
            app.secondbg.Visible = "on";


            app.StatusLabel.Visible = "on";
            app.UITable.Visible = "on";
            app.RollDiceButton.Visible = "on";
            app.SkipButton.Visible = "on";
            app.dice1.Visible = "on";
            app.dice2.Visible = "off";
            app.dice3.Visible = "off";
            app.dice4.Visible = "off";
            app.dice5.Visible = "off";
            app.dice6.Visible = "off";

            %app.dice5.Visible = "on"; % FIXED: Added missing semicolon
            
            %Disable and make Play and Help buttons invisible
            app.PlayButton.Enable = "off";
            app.PlayButton.Visible = "off";
            app.Sensor = sensorAnalyzer();
            app.GestureDetector = GestureDetector(app.Sensor);
            app.startGesturePolling(); 
            
            pathToMLAPP = fileparts(mfilename('fullpath'));
                musicFile = fullfile(pathToMLAPP, 'music1.mp3'); % <-- CHANGE THIS
 
                [app.AudioData, app.AudioSampleRate] = audioread(musicFile);
                app.AudioPlayer = audioplayer(app.AudioData, app.AudioSampleRate);
 
                % When the song ends, call RestartMusic to loop it
                app.AudioPlayer.StopFcn = createCallbackFcn(app, @RestartMusic, true);
 
                play(app.AudioPlayer);


                %dice sound effec
                 diceFile = fullfile(pathToMLAPP, 'diceroll.mp3'); % <-- CHANGE THIS
                [app.DiceAudioData, app.DiceAudioSampleRate] = audioread(diceFile);
                app.DiceAudioPlayer = audioplayer(app.DiceAudioData, app.DiceAudioSampleRate);
            
                % If the file isn't found or audio fails, show a warning
                % but don't crash the game
                warning('DiceGame:AudioError', 'Could not load music: %s');
        
       
        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)
          if isempty(app.Game) || app.Game.GameOver
    return;
          end
          

if app.Game.CurrentPlayer ~= app.PlayerNum
    app.UpdateGameDisplay();
    return;
end
            roll  = app.Game.RollDice();


            % NEW: Play the dice roll sound effect when button is pressed
            % Stops any previous roll sound first so it does not stack


            if ~isempty(app.DiceAudioPlayer) && isvalid(app.DiceAudioPlayer)
                    stop(app.DiceAudioPlayer);
                    play(app.DiceAudioPlayer);
            end


            % Group your 6 image components into a cell array
            diceComponents = {app.dice1, app.dice2, app.dice3, ...
                              app.dice4, app.dice5, app.dice6};
                          
            % Number of frames to shuffle
            numShuffles = 20; 
            
            % Helper function to hide all dice images
            for k = 1:6
                diceComponents{k}.Visible = "off";
            end
            
            % Shuffle animation loop
            for i = 1:numShuffles
                % Generate a random dice index (1 through 6)
                currentRoll = randi([1, 6]); 
                
                % Flash the current random dice image on
                diceComponents{currentRoll}.Visible = "on";
                
                % Pause briefly to create the illusion of rolling
                pause(0.08);
                
                % Turn it back off before the next iteration
                diceComponents{currentRoll}.Visible = "off";
                
                % Force the UI to refresh immediately
                drawnow(); 
            end
            
            % Determine and display the final roll

            diceComponents{roll}.Visible = "on";


           %Update UI
           app.UpdateGameDisplay();

        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
           if isempty(app.Game) || app.Game.GameOver
    uialert(app.UIFigure, 'Game is over!', 'Game Over');
    return;
           end


if app.Game.CurrentPlayer ~= app.PlayerNum
    app.UpdateGameDisplay();
    return;
end


            skippedPlayer = app.Game.CurrentPlayer;
            app.Game.skipTurn();

           %Update UI
           app.UpdateGameDisplay();

            % Penalty Message
            uialert(app.UIFigure, sprintf('Player %d skipped their turn!\nThey will have to roll twice on their next turn.', ...
                    skippedPlayer), 'Turn Skipped');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.menubg = uiimage(app.UIFigure);
            app.menubg.ImageSource = fullfile(pathToMLAPP, 'backgroundgame.png');
            app.secondbg = uiimage(app.UIFigure);
            app.secondbg.Visible = 'off';
            app.secondbg.ImageSource = fullfile(pathToMLAPP, 'bg2.png');
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Visible = 'off';
            app.RollDiceButton.Text = 'Roll Dice';
            app.dice1 = uiimage(app.UIFigure);
            app.dice1.Visible = 'off';
            app.dice1.ImageSource = fullfile(pathToMLAPP, 'one.png');
            app.dice5 = uiimage(app.UIFigure);
            app.dice5.Visible = 'off';
            app.dice5.ImageSource = fullfile(pathToMLAPP, 'five.png');
            app.dice6 = uiimage(app.UIFigure);
            app.dice6.Visible = 'off';
            app.dice6.ImageSource = fullfile(pathToMLAPP, 'six.png');
            app.dice4 = uiimage(app.UIFigure);
            app.dice4.Visible = 'off';
            app.dice4.ImageSource = fullfile(pathToMLAPP, 'four.png');
            app.dice3 = uiimage(app.UIFigure);
            app.dice3.Visible = 'off';
            app.dice3.ImageSource = fullfile(pathToMLAPP, 'three.png');
            app.dice2 = uiimage(app.UIFigure);
            app.dice2.Visible = 'off';
            app.dice2.ImageSource = fullfile(pathToMLAPP, 'two.png');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player 1'; 'Player 2'; 'Limit'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Enable = 'off';
            app.SkipButton.Visible = 'off';
            app.SkipButton.Text = 'Skip';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiceGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end