classdef gameDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Image_12    matlab.ui.control.Image
        Image_11    matlab.ui.control.Image
        Image_10    matlab.ui.control.Image
        Image_9     matlab.ui.control.Image
        Image_8     matlab.ui.control.Image
        Image_7     matlab.ui.control.Image
        Image_6     matlab.ui.control.Image
        Image_5     matlab.ui.control.Image
        Image_4     matlab.ui.control.Image
        Image_3     matlab.ui.control.Image
        Image_2     matlab.ui.control.Image
        Image       matlab.ui.control.Image
        secondbg    matlab.ui.control.Image
        HelpButton  matlab.ui.control.Button
        PlayButton  matlab.ui.control.Button
        menubg      matlab.ui.control.Image
    end

    
    properties (Access = private)
        Cards
        CardsClickable = false % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.AutoResizeChildren = "on";

            app.UIFigure.WindowState = "maximized";

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.CardsClickable = false;
            % switch backgrounds
            app.menubg.Visible = "off";
            app.secondbg.Visible = "on";
            
            % Disable and make Play and Help buttons invisible
            app.PlayButton.Enable = "off";
            app.PlayButton.Visible = "off";
            app.HelpButton.Visible = "off";
            app.HelpButton.Enable = "off";


            %create cards variable countaining all cards
            cards = [app.Image, app.Image_2, app.Image_3, app.Image_4,...
         app.Image_5, app.Image_6, app.Image_7, app.Image_8,...
         app.Image_9, app.Image_10, app.Image_11, app.Image_12];

            app.Cards = cards;
            
            images = {"cardimage1.png", "cardimage2.png","cardimage3.png",...
                "cardimage4.png", "cardimage5.png", "cardimage1.png"};

            % Creates pairs ( 2 x # of unique images)
            deck = [images;images];
            % turns cards visible and unables them
            for k = 1:numel(cards)
                cards(k).Visible = "on";
            end


            % let users prepare
            
            pause(5);


            %Give user 3 seconds to look
            % Randomizes card order/ decks order
            cardOrder = deck(randperm(numel(deck)));

            for k = 1:numel(cardOrder)
               
               cards(k).ImageSource = cardOrder{k};
            end
            pause(3)


        % flip cards back
        for k = 1:numel(cardOrder)
            cards(k).ImageSource = "backOfCards.png";
            cards(k).Enable = "off";   % disable clicking during setup
        end

            app.CardsClickable = false;
            pause(0.5);


            %% Enable Interaction


            %Make cards selectable
            for k = 1:numel(cards)
                cards(k).Enable = "on";
                cards(k).ImageClickedFcn = createCallbackFcn(app,@imageClick,true);
            end
            app.CardsClickable = true;
        end

        % Image clicked function: Image
        function imageClick(app, event)
            if app.CardsClickable == false
                return;
            end
            disp("A card was clicked");

             clickedCard = event.Source;

            cards = app.Cards;
            clickedCardNum = find(cards == clickedCard);
            
             fprintf("Card %d clicked\n", clickedCardNum);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.menubg = uiimage(app.UIFigure);
            app.menubg.ImageSource = fullfile(pathToMLAPP, 'backgroundgame.png');
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.secondbg = uiimage(app.UIFigure);
            app.secondbg.Visible = 'off';
            app.secondbg.ImageSource = fullfile(pathToMLAPP, 'bg2.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @imageClick, true);
            app.Image.Enable = 'off';
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'backOfCards.png');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.Enable = 'off';
            app.Image_2.Visible = 'off';
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'backOfCards.png');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.Enable = 'off';
            app.Image_3.Visible = 'off';
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'backOfCards.png');
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.Enable = 'off';
            app.Image_4.Visible = 'off';
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'backOfCards.png');
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.Enable = 'off';
            app.Image_5.Visible = 'off';
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'backOfCards.png');
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.Enable = 'off';
            app.Image_6.Visible = 'off';
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'backOfCards.png');
            app.Image_7 = uiimage(app.UIFigure);
            app.Image_7.Enable = 'off';
            app.Image_7.Visible = 'off';
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'backOfCards.png');
            app.Image_8 = uiimage(app.UIFigure);
            app.Image_8.Enable = 'off';
            app.Image_8.Visible = 'off';
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'backOfCards.png');
            app.Image_9 = uiimage(app.UIFigure);
            app.Image_9.Enable = 'off';
            app.Image_9.Visible = 'off';
            app.Image_9.ImageSource = fullfile(pathToMLAPP, 'backOfCards.png');
            app.Image_10 = uiimage(app.UIFigure);
            app.Image_10.Enable = 'off';
            app.Image_10.Visible = 'off';
            app.Image_10.ImageSource = fullfile(pathToMLAPP, 'backOfCards.png');
            app.Image_11 = uiimage(app.UIFigure);
            app.Image_11.Enable = 'off';
            app.Image_11.Visible = 'off';
            app.Image_11.ImageSource = fullfile(pathToMLAPP, 'backOfCards.png');
            app.Image_12 = uiimage(app.UIFigure);
            app.Image_12.Visible = 'off';
            app.Image_12.ImageSource = fullfile(pathToMLAPP, 'backOfCards.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameDisplay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end